classdef FFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        Frequency1EditFieldLabel  matlab.ui.control.Label
        Frequency1EditField       matlab.ui.control.NumericEditField
        Frequency2EditFieldLabel  matlab.ui.control.Label
        Frequency2EditField       matlab.ui.control.NumericEditField
        PlotButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            Fs = 1000;            % Sampling frequency                    
            T = 1/Fs;             % Sampling period       
            L = 1500;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            p=app.Frequency1EditField.Value;
            q=app.Frequency2EditField.Value;
          
            y=0.7*sin(2*pi*p*t)+0.3*sin(2*pi*q*t);
            noisy_y = y + 0.03 * randn(size(t));
            a=fft(y);
            b=abs(a);
            plot(app.UIAxes,t,noisy_y);
            plot(app.UIAxes2,b);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Noisy Input')
            xlabel(app.UIAxes, 'Time in sec')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'Frequency in KHz')
            ylabel(app.UIAxes2, 'Y')
            app.Frequency1EditFieldLabel = uilabel(app.UIFigure);
            app.Frequency1EditFieldLabel.Text = 'Frequency 1';
            app.Frequency1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Frequency2EditFieldLabel = uilabel(app.UIFigure);
            app.Frequency2EditFieldLabel.Text = 'Frequency 2';
            app.Frequency2EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end